classdef performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        graph          matlab.ui.control.UIAxes
        plotter        matlab.ui.control.Button
        status         matlab.ui.container.ButtonGroup
        reset          matlab.ui.control.ToggleButton
        pace           matlab.ui.control.ToggleButton
        rpe            matlab.ui.control.ToggleButton
        progress       matlab.ui.control.ToggleButton
        status_result  matlab.ui.control.NumericEditField
        Label          matlab.ui.control.Label
        green          matlab.ui.control.Lamp
        panel          matlab.ui.container.Panel
        Knob           matlab.ui.control.DiscreteKnob
        Gauge          matlab.ui.control.SemicircularGauge
    end

    methods (Access = private)

        % Button pushed function: plotter
        function plotterButtonPushed(app, event)
          p= evalin('base','normal');
          f= evalin('base','forecasty');
          pro=evalin('base','prog');
          %p=[p;0;0];
          %f=[0;0;0;0;f];
          n=1:1:length(p);
          n1=length(p)+1:1:length(p)+length(f);
          n2=length(p):1/10:length(p)+1;
          k=p(end):(f(1)-p(end))/10:f(1);
          len=1:1:length(p)+length(f);
          pro=pro*ones(1,length(p)+length(f));
          plot(app.graph,n,p,'b',n1,f,'m',n2,k,'.',len,pro,'-');
          legend(app.graph,'so far progress','predited','bridge','current');
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            t=evalin('base','t');
            d=evalin('base','d');
            a=evalin('base','a');
            v=evalin('base','v');
            c=evalin('base','c');
            if isequal(value,'Tension')
                app.Gauge.Value=t;
            elseif isequal(value,'Depression')
                app.Gauge.Value=d;
            elseif isequal(value,'Anger')
                app.Gauge.Value=a;
            elseif isequal(value,'Vigour')
                app.Gauge.Value=v;
            elseif isequal(value,'Confusion')
                app.Gauge.Value=c;
            else
                app.Gauge.Value=0;
            end
        end

        % Selection changed function: status
        function statusSelectionChanged(app, event)
            selectedButton = app.status.SelectedObject;
            prob=evalin('base','probs1');
            prog=evalin('base','prog');
            rp=evalin('base','rpe');
            if prog>=70
                app.green.Color='green';
            elseif prog<70 && prog>=50
                app.green.Color='yellow';   
            else
                app.green.Color='red';
            end
            if isequal(selectedButton,app.progress)
               app.status_result.Value=double(prog);
            elseif isequal(selectedButton,app.pace)
               app.status_result.Value=double(100*prob);
            elseif isequal(selectedButton,app.rpe)
               app.status_result.Value=rp/10*100;
            else
               app.status_result.Value=0;
            end
        end

        % Size changed function: panel
        function panelSizeChanged(app, event)
            position = app.panel.Position;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'My Progress')
            xlabel(app.graph, 'weeks')
            ylabel(app.graph, 'performance')
            app.plotter = uibutton(app.UIFigure, 'push');
            app.plotter.ButtonPushedFcn = createCallbackFcn(app, @plotterButtonPushed, true);
            app.plotter.Text = 'graph';
            app.status = uibuttongroup(app.UIFigure);
            app.status.SelectionChangedFcn = createCallbackFcn(app, @statusSelectionChanged, true);
            app.status.Title = 'Status';
            app.reset = uitogglebutton(app.status);
            app.reset.Text = 'Reset';
            app.reset.Value = true;
            app.pace = uitogglebutton(app.status);
            app.pace.Text = 'Pace';
            app.rpe = uitogglebutton(app.status);
            app.rpe.Text = 'RPE';
            app.progress = uitogglebutton(app.status);
            app.progress.Text = 'Performance';
            app.status_result = uieditfield(app.status, 'numeric');
            app.status_result.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.status);
            app.Label.Text = '%';
            app.green = uilamp(app.status);
            app.panel = uipanel(app.UIFigure);
            app.panel.Title = 'Mood States';
            app.panel.SizeChangedFcn = createCallbackFcn(app, @panelSizeChanged, true);
            app.Knob = uiknob(app.panel, 'discrete');
            app.Knob.Items = {'Reset', 'Tension', 'Depression', 'Anger', 'Vigour', 'Confusion'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Reset';
            app.Gauge = uigauge(app.panel, 'semicircular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = performance

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end