classdef performance_current < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        reppy                     matlab.ui.control.NumericEditField
        ActiveRepsEditFieldLabel  matlab.ui.control.Label
        button                    matlab.ui.control.Button
        StatusPanel               matlab.ui.container.Panel
        light                     matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        result                    matlab.ui.control.NumericEditField
        status                    matlab.ui.container.ButtonGroup
        performance               matlab.ui.control.ToggleButton
        RPE                       matlab.ui.control.ToggleButton
        pace                      matlab.ui.control.ToggleButton
        reset                     matlab.ui.control.ToggleButton
        MoodStatesPanel           matlab.ui.container.Panel
        Gauge                     matlab.ui.control.SemicircularGauge
        Knob                      matlab.ui.control.DiscreteKnob
        graph                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: StatusPanel
        function StatusPanelSizeChanged(app, event)
            position = app.StatusPanel.Position;
            
        end

        % Selection changed function: status
        function statusSelectionChanged(app, event)
            selectedButton = app.status.SelectedObject;
            prob=evalin('base','probs1');
            prob=100*prob;
            prog=evalin('base','prog');
            rp=evalin('base','rpe');
            if prog>=70
                app.light.Color='green';
            elseif prog<70 && prog>=50
                app.light.Color='yellow';   
            else
                app.light.Color='red';
            end
            if isequal(selectedButton,app.performance)
               app.result.Value=double(prog);
            elseif isequal(selectedButton,app.pace)
               app.result.Value=double(prob);
            elseif isequal(selectedButton,app.RPE)
               app.result.Value=rp/10*100;
            else
               app.result.Value=0;
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            t=evalin('base','t');
            d=evalin('base','d');
            a=evalin('base','a');
            v=evalin('base','v');
            c=evalin('base','c');
            if isequal(value,'Tension')
                app.Gauge.Value=t;
            elseif isequal(value,'Depression')
                app.Gauge.Value=d;
            elseif isequal(value,'Anger')
                app.Gauge.Value=a;
            elseif isequal(value,'Vigour')
                app.Gauge.Value=v;
            elseif isequal(value,'Confusion')
                app.Gauge.Value=c;
            else
                app.Gauge.Value=0;
            end
        end

        % Button pushed function: button
        function buttonButtonPushed(app, event)
           % load network_test.mat
            sig=evalin('base','VarName1');
            t=0:1/256:(length(sig)-1)/256;
            b =1/100*ones(100,1);

            a = 1;

            magNoG= filter(b,a,sig);

            for i=1:length(magNoG)
                if magNoG(i)<0
                magNoG(i)=0;
                end
            end
            minPeakHeight = std(magNoG);
            [pks,locs] = findpeaks(magNoG,'MINPEAKHEIGHT',minPeakHeight);
            numSteps = numel(pks);
            plot(app.graph,t,sig,'-b',t(locs),sig(locs),':rs');
            legend(app.graph,'Activity','Detetected reps');
            %plot(app.graph,locs,sig(locs), 'r', 'Marker', 'v', 'LineStyle', 'none');
            %xlabel(app.graph,'Time (s)');
            %ylabel(app.graph,'Acceleration Magnitude, No Gravity (m/s^2)');
            app.reppy.Value=numSteps;
        end

        % Value changed function: result
        function resultValueChanged(app, event)
            value = app.result.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'My reps')
            xlabel(app.graph, 'time(s)')
            ylabel(app.graph, 'acceleration(m/s/s)')
            app.MoodStatesPanel = uipanel(app.UIFigure);
            app.MoodStatesPanel.Title = 'Mood States';
            app.Knob = uiknob(app.MoodStatesPanel, 'discrete');
            app.Knob.Items = {'Reset', 'Tension', 'Depression', 'Anger', 'Vigour', 'Confusion'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Reset';
            app.Gauge = uigauge(app.MoodStatesPanel, 'semicircular');
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.StatusPanel.SizeChangedFcn = createCallbackFcn(app, @StatusPanelSizeChanged, true);
            app.status = uibuttongroup(app.StatusPanel);
            app.status.SelectionChangedFcn = createCallbackFcn(app, @statusSelectionChanged, true);
            app.reset = uitogglebutton(app.status);
            app.reset.Text = 'Reset';
            app.reset.Value = true;
            app.pace = uitogglebutton(app.status);
            app.pace.Text = 'Pace';
            app.RPE = uitogglebutton(app.status);
            app.RPE.Text = 'RPE';
            app.performance = uitogglebutton(app.status);
            app.performance.Text = 'Performance';
            app.result = uieditfield(app.StatusPanel, 'numeric');
            app.result.ValueChangedFcn = createCallbackFcn(app, @resultValueChanged, true);
            app.Label = uilabel(app.StatusPanel);
            app.Label.Text = '%';
            app.light = uilamp(app.StatusPanel);
            app.button = uibutton(app.UIFigure, 'push');
            app.button.ButtonPushedFcn = createCallbackFcn(app, @buttonButtonPushed, true);
            app.button.Text = 'Graph';
            app.ActiveRepsEditFieldLabel = uilabel(app.UIFigure);
            app.ActiveRepsEditFieldLabel.Text = 'Active Reps';
            app.reppy = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = performance_current

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end