classdef trainer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        grapher                        matlab.ui.control.UIAxes
        YourCurrentLoadEditFieldLabel  matlab.ui.control.Label
        current_load                   matlab.ui.control.NumericEditField
        kgLabel                        matlab.ui.control.Label
        NextDayStrategyPanel           matlab.ui.container.Panel
        NextloadEditFieldLabel         matlab.ui.control.Label
        next_load                      matlab.ui.control.NumericEditField
        CorrespondingrepsEditFieldLabel  matlab.ui.control.Label
        corresponding_reps             matlab.ui.control.NumericEditField
        kgLabel_2                      matlab.ui.control.Label
        enter                          matlab.ui.control.Button
        plotter                        matlab.ui.control.Button
        grapher1                       matlab.ui.control.UIAxes
        grapher2                       matlab.ui.control.UIAxes
        CurrentRepsEditFieldLabel      matlab.ui.control.Label
        current_reps                   matlab.ui.control.NumericEditField
        repspersetLabel                matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: plotter
        function plotterPushed(app, event)
          p= evalin('base','normal');
          f= evalin('base','forecasty');
          pro=evalin('base','prog');
          %p=[p;0;0];
          %f=[0;0;0;0;f];
          n=1:1:length(p);
          n1=length(p)+1:1:length(p)+length(f);
          n2=length(p):1/10:length(p)+1;
          k=p(end):(f(1)-p(end))/10:f(1);
          len=1:1:length(p)+length(f);
          pro=pro*ones(1,length(p)+length(f));
          plot(app.grapher,n,p,'b',n1,f,'m',n2,k,'.',len,pro,'-');
          legend(app.grapher,'so far progress','predited','bridge','current');   
        end

        % Button pushed function: enter
        function enterPushed(app, event)
          p1= evalin('base','normal1');
          f1= evalin('base','forecasty1');
          %p=[p;0;0];
          %f=[0;0;0;0;f];
          n=1:1:length(p1);
          n1=length(p1)+1:1:length(p1)+length(f1);
          n2=length(p1):1/10:length(p1)+1;
          k=p1(end):(f1(1)-p1(end))/10:f1(1);
          plot(app.grapher1,n,p1,'b',n1,f1,'m',n2,k,'.');
          legend(app.grapher1,'so far','predited');  
          
          p2= evalin('base','normal2');
          f2= evalin('base','forecasty2');
          %p=[p;0;0];
          %f=[0;0;0;0;f];
          n=1:1:length(p2);
          n1=length(p2)+1:1:length(p2)+length(f2);
          n2=length(p2):1/10:length(p2)+1;
          k=p2(end):(f2(1)-p2(end))/10:f2(1);
          plot(app.grapher2,n,p2,'b',n1,f2,'m',n2,k,'.');
          legend(app.grapher2,'so far','predited'); 
          
          if f1(1)>p1(end)
              app.next_load.Value=app.current_load.Value+2.5;
          else
              app.next_load.Value=app.current_load.Value;
          end
          
          if f2(2)>p2(end)
              app.corresponding_reps.Value=app.current_reps.Value-2;
          else
              app.corresponding_reps.Value=app.current_reps.Value;
          end


        end

        % Value changed function: current_load
        function current_loadValueChanged(app, event)
            value = app.current_load.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.grapher = uiaxes(app.UIFigure);
            title(app.grapher, 'Performance')
            xlabel(app.grapher, 'performace')
            ylabel(app.grapher, 'weeks')
            app.YourCurrentLoadEditFieldLabel = uilabel(app.UIFigure);
            app.YourCurrentLoadEditFieldLabel.Text = 'Your Current Load';
            app.current_load = uieditfield(app.UIFigure, 'numeric');
            app.current_load.ValueChangedFcn = createCallbackFcn(app, @current_loadValueChanged, true);
            app.kgLabel = uilabel(app.UIFigure);
            app.kgLabel.Text = 'kg';
            app.NextDayStrategyPanel = uipanel(app.UIFigure);
            app.NextDayStrategyPanel.Title = 'Next Day Strategy';
            app.NextloadEditFieldLabel = uilabel(app.NextDayStrategyPanel);
            app.NextloadEditFieldLabel.Text = 'Next load';
            app.next_load = uieditfield(app.NextDayStrategyPanel, 'numeric');
            app.CorrespondingrepsEditFieldLabel = uilabel(app.NextDayStrategyPanel);
            app.CorrespondingrepsEditFieldLabel.Text = 'Corresponding reps';
            app.corresponding_reps = uieditfield(app.NextDayStrategyPanel, 'numeric');
            app.kgLabel_2 = uilabel(app.NextDayStrategyPanel);
            app.kgLabel_2.Text = 'kg';
            app.enter = uibutton(app.UIFigure, 'push');
            app.enter.ButtonPushedFcn = createCallbackFcn(app, @enterPushed, true);
            app.enter.Text = 'Enter';
            app.plotter = uibutton(app.UIFigure, 'push');
            app.plotter.ButtonPushedFcn = createCallbackFcn(app, @plotterPushed, true);
            app.plotter.Text = 'Graph';
            app.grapher1 = uiaxes(app.UIFigure);
            title(app.grapher1, 'Rapidity')
            xlabel(app.grapher1, 'week')
            ylabel(app.grapher1, 'rapidity(%)')
            app.grapher2 = uiaxes(app.UIFigure);
            title(app.grapher2, 'Fatigue')
            xlabel(app.grapher2, 'week')
            ylabel(app.grapher2, 'fatigue(%)')
            app.CurrentRepsEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentRepsEditFieldLabel.Text = 'Current Reps';
            app.current_reps = uieditfield(app.UIFigure, 'numeric');
            app.repspersetLabel = uilabel(app.UIFigure);
            app.repspersetLabel.Text = 'reps per set';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trainer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end